classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SelectHRVvariableButtonGroup  matlab.ui.container.ButtonGroup
        mean_RRButton                 matlab.ui.control.RadioButton
        SNSindexButton                matlab.ui.control.RadioButton
        PNSindexButton                matlab.ui.control.RadioButton
        LoadmeasurementButton         matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data % the data that has been loaded
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadmeasurementButton
        function selectFile(app, event)
            fig = app.UIFigure;
            msg = ['Do you want to load an export with or without ...' ...
                ' manually configured samples?'];
            title = 'Choose file configuration';
            selection = uiconfirm(fig,msg,title, ...
           'Options',{'With samples','Without samples','Cancel'}, ...
           'DefaultOption',2,'CancelOption',3);
            
            if ~strcmp(selection, 'Cancel')
                [file, path] = uigetfile('*m');
                path2file = fullfile(path, file);
                if strcmp(selection, 'Without samples')
                    app.data = load(path2file);
                else
                    app.data = load(path2file);
                end
            end
        end

        % Selection changed function: SelectHRVvariableButtonGroup
        function selectHRVvariable(app, event)
            selectedButton = app.SelectHRVvariableButtonGroup.SelectedObject;
            selectedVar = selectedButton.Text;
            
            plot(app.UIAxes, app.data.Res.HRV.TimeVar.(selectedVar));
        end

        % Close request function: UIFigure
        function checkIfSaved(app, event)
            fig = app.UIFigure;
            msg = ['Did you save your evaluation? Otherwise it will' ...
                ' not be saved.'];
            title = 'Evaluation saved?';
            selection = uiconfirm(fig,msg,title, ...
           'Options',{'Yes','No'}, 'DefaultOption',2,'CancelOption',2);
            
            if strcmp(selection, 'Yes')
                delete(app)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {447, 447};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @checkIfSaved, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadmeasurementButton = uibutton(app.LeftPanel, 'push');
            app.LoadmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @selectFile, true);
            app.LoadmeasurementButton.Text = 'Load measurement';
            app.SelectHRVvariableButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectHRVvariableButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectHRVvariable, true);
            app.SelectHRVvariableButtonGroup.Title = 'Select HRV variable';
            app.PNSindexButton = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.PNSindexButton.Text = 'PNSindex';
            app.PNSindexButton.Value = true;
            app.SNSindexButton = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.SNSindexButton.Text = 'SNSindex';
            app.mean_RRButton = uiradiobutton(app.SelectHRVvariableButtonGroup);
            app.mean_RRButton.Text = 'mean_RR';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end