classdef createSampleFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CurrentfileLabel               matlab.ui.control.Label
        setgapbetweensamplesLabel      matlab.ui.control.Label
        selectlengthofsinglesampleLabel  matlab.ui.control.Label
        selectstartminuteofsamplecreationLabel  matlab.ui.control.Label
        ConfigureyoursamplesLabel      matlab.ui.control.Label
        SamplestarttimeEditField       matlab.ui.control.NumericEditField
        SamplestarttimeEditFieldLabel  matlab.ui.control.Label
        SampleshiftEditField           matlab.ui.control.NumericEditField
        SampleshiftEditFieldLabel      matlab.ui.control.Label
        MeasurementlengthminEditField  matlab.ui.control.NumericEditField
        MeasurementlengthminEditFieldLabel  matlab.ui.control.Label
        SamplesizeEditField            matlab.ui.control.NumericEditField
        SamplesizeEditFieldLabel       matlab.ui.control.Label
        nofileselectedLabel            matlab.ui.control.Label
        SelectfileButton               matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingapp % mainapp
        filename
        path2file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
          % Store main app object
          app.callingapp = mainapp;

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            importdata(app.callingapp, app.SamplestarttimeEditField.Value, ...
                app.SamplesizeEditField.Value, ...
                app.MeasurementlengthminEditField.Value, ...
                app.SampleshiftEditField.Value, ...
                app.filename, app.path2file);
            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)            
        end

        % Value changed function: SamplesizeEditField
        function SamplesizeEditFieldValueChanged(app, event)
            value = app.SamplesizeEditField.Value;
            
        end

        % Value changed function: MeasurementlengthminEditField
        function MeasurementlengthminEditFieldValueChanged(app, event)
            value = app.MeasurementlengthminEditField.Value;
            
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [file, path] = uigetfile('*.EDF');
            app.filename = file;
            app.path2file = path;
            set(app.nofileselectedLabel, 'Text', file);
            set(app.OKButton, 'Enable', 'on');
        end

        % Value changed function: SampleshiftEditField
        function SampleshiftEditFieldValueChanged(app, event)
            value = app.SampleshiftEditField.Value;
            
        end

        % Value changed function: SamplestarttimeEditField
        function SamplestarttimeEditFieldValueChanged(app, event)
            value = app.SamplestarttimeEditField.Value;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Enable = 'off';
            app.OKButton.Text = 'OK';
            app.SelectfileButton = uibutton(app.GridLayout, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.nofileselectedLabel = uilabel(app.GridLayout);
            app.nofileselectedLabel.Text = 'no file selected';
            app.SamplesizeEditFieldLabel = uilabel(app.GridLayout);
            app.SamplesizeEditFieldLabel.Text = 'Sample size';
            app.SamplesizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.SamplesizeEditField.Limits = [1 Inf];
            app.SamplesizeEditField.ValueChangedFcn = createCallbackFcn(app, @SamplesizeEditFieldValueChanged, true);
            app.SamplesizeEditField.Value = 1;
            app.MeasurementlengthminEditFieldLabel = uilabel(app.GridLayout);
            app.MeasurementlengthminEditFieldLabel.Text = 'Measurement length (min)';
            app.MeasurementlengthminEditField = uieditfield(app.GridLayout, 'numeric');
            app.MeasurementlengthminEditField.Limits = [1 Inf];
            app.MeasurementlengthminEditField.ValueChangedFcn = createCallbackFcn(app, @MeasurementlengthminEditFieldValueChanged, true);
            app.MeasurementlengthminEditField.Value = 1;
            app.SampleshiftEditFieldLabel = uilabel(app.GridLayout);
            app.SampleshiftEditFieldLabel.Text = 'Sample shift ';
            app.SampleshiftEditField = uieditfield(app.GridLayout, 'numeric');
            app.SampleshiftEditField.Limits = [1 Inf];
            app.SampleshiftEditField.ValueChangedFcn = createCallbackFcn(app, @SampleshiftEditFieldValueChanged, true);
            app.SampleshiftEditField.Value = 1;
            app.SamplestarttimeEditFieldLabel = uilabel(app.GridLayout);
            app.SamplestarttimeEditFieldLabel.Text = 'Sample start time';
            app.SamplestarttimeEditField = uieditfield(app.GridLayout, 'numeric');
            app.SamplestarttimeEditField.ValueChangedFcn = createCallbackFcn(app, @SamplestarttimeEditFieldValueChanged, true);
            app.ConfigureyoursamplesLabel = uilabel(app.GridLayout);
            app.ConfigureyoursamplesLabel.Text = 'Configure your samples';
            app.selectstartminuteofsamplecreationLabel = uilabel(app.GridLayout);
            app.selectstartminuteofsamplecreationLabel.Text = '(select start minute of sample creation)';
            app.selectlengthofsinglesampleLabel = uilabel(app.GridLayout);
            app.selectlengthofsinglesampleLabel.Text = '(select length of single sample)';
            app.setgapbetweensamplesLabel = uilabel(app.GridLayout);
            app.setgapbetweensamplesLabel.Text = '(set gap between samples)';
            app.CurrentfileLabel = uilabel(app.GridLayout);
            app.CurrentfileLabel.Text = 'Current file:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = createSampleFile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end