classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        RightPanel                  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        ListBox                     matlab.ui.control.ListBox
        DropDown                    matlab.ui.control.DropDown
        SetstressintervalPanel      matlab.ui.container.Panel
        Switch                      matlab.ui.control.Switch
        LengthminEditField          matlab.ui.control.NumericEditField
        LengthminEditFieldLabel     matlab.ui.control.Label
        StarttimeminEditField       matlab.ui.control.NumericEditField
        StarttimeminEditFieldLabel  matlab.ui.control.Label
        nomeasurementselectedLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        OpenMenu                    matlab.ui.container.Menu
        CreateparametersettingscsvfileMenu  matlab.ui.container.Menu
        LoadmeasurementMenu         matlab.ui.container.Menu
        PlotMenu                    matlab.ui.container.Menu
        SaveplotMenu                matlab.ui.container.Menu
        RestoreviewMenu             matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        StepbystepguideMenu         matlab.ui.container.Menu
        ContactMenu                 matlab.ui.container.Menu
    end

    
    properties (Access = private)
        data % the mat-file that has been loaded
        setup % the setup mat-file
        createSampleFile % the popup to create sample config from
        stressIntervall % the intervall that is highlighted red
        loaded % is set to true when data is loaded
    end
    
    methods (Access = public)
        
        function importdata(~, sampleStart, sampleLength, fileLength, sampleShift, filename, path)

             if ~createCSV(sampleStart, sampleLength, fileLength, sampleShift, filename, path)
                msgbox("Something went wrong, please try again!");
             end
             if isunix
                [status, cmdout] = system("cd /; open Applications/" + ...
                    "KubiosHRVPremium/application/KubiosHRVPremium.app");
                if status
                    msgbox("There was an issue starting Kubios HRV Premium! Please try it manually.");
                end
             end
             if ispc
                % TODO: add Windows Support here
             end
        end  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function setupApp(app)
        
              app.setup = load('assets/setup.mat');
              app.DropDownValueChanged();
              
        end

        % Menu selected function: LoadmeasurementMenu
        function selectFile(app, event)
            % deactivate buttons while loading file
            app.ListBox.Enable = "off";


            fig = app.UIFigure;
            msg = ['Do you want to load an export with or without' ...
                ' manually configured samples?'];
            title = 'Choose file configuration';
            selection = uiconfirm(fig,msg,title, ...
           'Options',{'With samples','Without samples','Cancel'}, ...
           'DefaultOption',1,'CancelOption',3);
            
            if ~strcmp(selection, 'Cancel')
                [file, path] = uigetfile('*.mat');
                path2file = fullfile(path, file);
                if strcmp(selection, 'Without samples')
                    app.data = WithoutSamplesParser(path2file);
                else
                    app.data = WithSamplesParser(path2file);
                end
                app.ListBoxValueChanged();
                % show name of selected measurement file
                set(app.nomeasurementselectedLabel, 'Text', file);
                app.loaded = true;
            end
            
            if app.loaded
                app.ListBox.Enable = "on";
                app.DropDown.Enable = "on";   
            end

        end

        % Close request function: UIFigure
        function checkIfSaved(app, event)
            fig = app.UIFigure;
            msg = ['Did you save your evaluation? Otherwise it will' ...
                ' not be saved.'];
            title = 'Evaluation saved?';
            selection = uiconfirm(fig,msg,title, ...
           'Options',{'Yes','No'}, 'DefaultOption',2,'CancelOption',2);
            
            if strcmp(selection, 'Yes')
                delete(app)
            end
        end

        % Menu selected function: CreateparametersettingscsvfileMenu
        function createexportfileButtonPushed(app, event)
            app.createSampleFile = createSampleFile(app);
        end

        % Menu selected function: SaveplotMenu
        function savePlot(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportgraphics(app.UIAxes, [filepath filename]);
            end
        end

        % Size changed function: UIFigure
        function updateAppLayout(app, event)

        end

        % Menu selected function: RestoreviewMenu
        function RestoreviewMenuSelected(app, event)
            zoom(app.UIAxes, 'out');
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'On')
                rectX = [app.StarttimeminEditField.Value, app.StarttimeminEditField.Value + app.LengthminEditField.Value];
                rectY = ylim(app.UIAxes);
                app.stressIntervall = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.2);
                ylim(app.UIAxes, rectY);
            else
                delete(app.stressIntervall);
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
            if value == "Time-Domain"
                app.ListBox.Items = {app.setup.variablesStruct.timeDomain(:).short};
                app.ListBox.ItemsData = {app.setup.variablesStruct.timeDomain(:).index};
            elseif value =="Frequency-Domain FFT spectrum"
                app.ListBox.Items = {app.setup.variablesStruct.frequencyDomain(:).short};
                app.ListBox.ItemsData = {app.setup.variablesStruct.frequencyDomain(:).index};
            elseif value =="Frequency-Domain AR spectrum"
                app.ListBox.Items = {app.setup.variablesStruct.frequencyDomain(:).short};
                app.ListBox.ItemsData = {app.setup.variablesStruct.frequencyDomain(:).index};
            elseif value =="Nonlinear"
                app.ListBox.Items = {app.setup.variablesStruct.nonlinear(:).short};
                app.ListBox.ItemsData = {app.setup.variablesStruct.nonlinear(:).index};
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.data.plotToAxes(app.setup, app.UIAxes, value, app.DropDown.Value);
            % keep stress intervall set after changing variable
            app.SwitchValueChanged();
        end

        % Menu selected function: ContactMenu
        function ContactMenuSelected(app, event)
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            f = msgbox(["\bf\fontsize{10} Tim Weiß:"; "\rm\fontsize{9} timweiss01@gmx.de"; "\bf\fontsize{10} Jona Krumrein:"; "\rm\fontsize{9} krumrein.jona@gmail.com"], "Contact", "help", CreateStruct);
           
            text = findall(f, 'Type', 'Text');
            deltaWidth = sum(text.Extent([1,3]))-f.Position(3) + text.Extent(1);
            deltaHeight = sum(text.Extent([2,4]))-f.Position(4) + 10;
            f.Position([3,4]) = f.Position([3,4]) + [deltaWidth, deltaHeight];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @checkIfSaved, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            zlabel(app.UIAxes, 'Z')
            app.nomeasurementselectedLabel = uilabel(app.GridLayout2);
            app.nomeasurementselectedLabel.Text = 'no measurement selected';
            app.SetstressintervalPanel = uipanel(app.GridLayout2);
            app.SetstressintervalPanel.Title = 'Set stress interval';
            app.StarttimeminEditFieldLabel = uilabel(app.SetstressintervalPanel);
            app.StarttimeminEditFieldLabel.Text = 'Start time (min)';
            app.StarttimeminEditField = uieditfield(app.SetstressintervalPanel, 'numeric');
            app.StarttimeminEditField.Limits = [0 Inf];
            app.LengthminEditFieldLabel = uilabel(app.SetstressintervalPanel);
            app.LengthminEditFieldLabel.Text = 'Length (min)';
            app.LengthminEditField = uieditfield(app.SetstressintervalPanel, 'numeric');
            app.LengthminEditField.Limits = [0 Inf];
            app.Switch = uiswitch(app.SetstressintervalPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'Time-Domain', 'Frequency-Domain FFT spectrum', 'Frequency-Domain AR spectrum', 'Nonlinear'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Time-Domain';
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {'Mean RR', 'Stress index', ''};
            app.ListBox.ItemsData = {'Mean RR', 'Stress index', ''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Enable = 'off';
            app.ListBox.Value = 'Mean RR';
            app.OpenMenu = uimenu(app.UIFigure);
            app.OpenMenu.Text = 'Open';
            app.CreateparametersettingscsvfileMenu = uimenu(app.OpenMenu);
            app.CreateparametersettingscsvfileMenu.MenuSelectedFcn = createCallbackFcn(app, @createexportfileButtonPushed, true);
            app.CreateparametersettingscsvfileMenu.Separator = 'on';
            app.CreateparametersettingscsvfileMenu.Text = 'Create parametersettings csv-file';
            app.LoadmeasurementMenu = uimenu(app.OpenMenu);
            app.LoadmeasurementMenu.MenuSelectedFcn = createCallbackFcn(app, @selectFile, true);
            app.LoadmeasurementMenu.Text = 'Load measurement';
            app.PlotMenu = uimenu(app.UIFigure);
            app.PlotMenu.Text = 'Plot';
            app.SaveplotMenu = uimenu(app.PlotMenu);
            app.SaveplotMenu.MenuSelectedFcn = createCallbackFcn(app, @savePlot, true);
            app.SaveplotMenu.Text = 'Save plot';
            app.RestoreviewMenu = uimenu(app.PlotMenu);
            app.RestoreviewMenu.MenuSelectedFcn = createCallbackFcn(app, @RestoreviewMenuSelected, true);
            app.RestoreviewMenu.Text = 'Restore view';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.StepbystepguideMenu = uimenu(app.HelpMenu);
            app.StepbystepguideMenu.Text = 'Step-by-step guide';
            app.ContactMenu = uimenu(app.HelpMenu);
            app.ContactMenu.MenuSelectedFcn = createCallbackFcn(app, @ContactMenuSelected, true);
            app.ContactMenu.Text = 'Contact';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @setupApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end